classdef Matlab_sif_readUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SifFileRead               matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenFileMenu              matlab.ui.container.Menu
        OpenFolderMenu            matlab.ui.container.Menu
        ShowFileinListBoxMenu     matlab.ui.container.Menu
        SaveMenu                  matlab.ui.container.Menu
        ExportMenu                matlab.ui.container.Menu
        ExitMenu                  matlab.ui.container.Menu
        FigureMenu                matlab.ui.container.Menu
        SetMenu                   matlab.ui.container.Menu
        CalculationMenu           matlab.ui.container.Menu
        SubtractBackgroundMenu    matlab.ui.container.Menu
        ShowAllFigureMenu         matlab.ui.container.Menu
        Menu                      matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        MainTab                   matlab.ui.container.Tab
        XButton                   matlab.ui.control.Button
        FilefolderEditFieldLabel  matlab.ui.control.Label
        FilefolderEditField       matlab.ui.control.EditField
        Button_2                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        Button_4                  matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        FileListBoxLabel          matlab.ui.control.Label
        FileListBox               matlab.ui.control.ListBox
        Button_6                  matlab.ui.control.Button
        SetPanel                  matlab.ui.container.Panel
        TabGroup2                 matlab.ui.container.TabGroup
        AxisTab                   matlab.ui.container.Tab
        XminSpinnerLabel          matlab.ui.control.Label
        XminSpinner               matlab.ui.control.Spinner
        XmaxSpinnerLabel          matlab.ui.control.Label
        XmaxSpinner               matlab.ui.control.Spinner
        YminSpinnerLabel          matlab.ui.control.Label
        YminSpinner               matlab.ui.control.Spinner
        YmaxSpinnerLabel          matlab.ui.control.Label
        YmaxSpinner               matlab.ui.control.Spinner
        LineTab                   matlab.ui.container.Tab
        ColorDropDownLabel        matlab.ui.control.Label
        ColorDropDown             matlab.ui.control.DropDown
        OkButton                  matlab.ui.control.Button
        ApplyButton               matlab.ui.control.Button
        BackgroundTab             matlab.ui.container.Tab
        Button_7                  matlab.ui.control.Button
        FileListBox_2Label        matlab.ui.control.Label
        FileListBox_2             matlab.ui.control.ListBox
        UIAxes_2                  matlab.ui.control.UIAxes
        clearButton               matlab.ui.control.Button
    end


    properties (Access = private)
        Xmin                             %   X坐标轴最小值
        Xmax                             %   X坐标轴最大值
        Ymin                             %   Y坐标轴最小值
        Ymax                             %   Y坐标轴最大值
        
    end

    methods (Access = private)
        
        
        % 画信号的图像
        function statu = singlePlot(app,uiaxes,calibvals,data,xtype,xunit,ytype,yunit)
            
            if(uiaxes == 0)
                % 画普通图窗
                fig=figure;
                plot(calibvals,data);
                y_max = max(data);
                size = length(calibvals);
                xlim([calibvals(1) calibvals(size)]);
                ylim([0 y_max*1.05]);
                title('spectrum');
                xlabel({xtype;xunit});
                ylabel({ytype;yunit});
                grid on;        
                disp([app.Xmin app.Xmax app.Ymin app.Ymax]);
                % 判断是否为有效设置
                if(app.Xmin < app.Xmax)
                    xlim([app.Xmin app.Xmax]);
                end
                if(app.Ymin < app.Ymax)
                    ylim([app.Ymin app.Ymax]);
                end
                  % 保存文件
                statu = app.saveSifToJpg(fig);
                
            else
                % 画到UIAxes中
                y_max = max(data);
                size = length(calibvals);
                uiaxes.XLim = [calibvals(1) calibvals(size)];
                uiaxes.YLim = [0 y_max*1.05];
                plot(uiaxes,calibvals,data);
                title(uiaxes,'spectrum','fontsize',14);
                xlabel(uiaxes,{xtype;xunit});
                ylabel(uiaxes,{ytype;yunit});
                uiaxes.YAxis.Direction = 'normal';   
            end
        end
    
    
        % 显示image模式的图像
        function statu = imageShow(app,uiaxes,data,xtype,ytype)
            if(uiaxes == 0)
                % 画到figure窗口，并保存
                fig = figure;
                data = data';
                xlim([0 1024]);
                ylim([0 256]);
                imagesc(data);
                title('Image');
                xlabel({xtype});
                ylabel({ytype});
                colorbar();
                statu = app.saveSifToJpg(fig);
            else
                % 画到UIAxes窗口
                data = data';
                uiaxes.XLim = [0 1024];
                uiaxes.YLim = [0 256];
                imagesc(uiaxes,data);
                title(uiaxes,'Image');
                xlabel(uiaxes,{xtype});
                ylabel(uiaxes,{ytype});
                %colorbar(app.UIAxes);
            end
        end

    
        % 获取原始数据，并且画图显示
         function [pattern,calibvals,data,xtype,xunit,ytype,yunit] = getOrignalDataPlot(app,filePath,uiaxes,isPlot)
%             输入参数：
%                 filePath为sif文件路径,要求char类型
%                 uiaxes 为坐标轴，如果 0 ，则绘制在figure中，并且可以保存成图片
%                 isPlot 为是否画图，1为画图，0为不画图
            
            % 获取原始数据
            [pattern,calibvals,data,xtype,xunit,ytype,yunit] = MatlabUI_sif_show(filePath);
            
            % 判断是否画图
            if(isPlot)
                % 判断数据类型，然后显示出来； pattern=0为信号模式，pattern=4为图像模式
                if(pattern == '0')
                    app.singlePlot(uiaxes,calibvals,data,xtype,xunit,ytype,yunit);            
                elseif(pattern == '4')
                    app.imageShow(uiaxes,data,xtype,ytype);
                end
            end
        end
    
    
        % 保存sif数据成为图片
        function statu = saveSifToJpg(app,fig)
            [f,p]=uiputfile({'*.jpg';'*.png';'*.pdf'},'保存文件');
            if(length(f) > 1)
                str=strcat(p,f);
                if(contains(f,'.jpg'))
                    print(fig,str,'-djpeg')
                elseif(contains(f,'.png'))
                    print(fig,str,'-dpng')
                elseif(contains(f,'.pdf'))
                    print(fig,str,'-dpdf')
                end
                statu = 1;
            else
                statu = 0;
            end
        end
    
    
         % 显示文件列表,追加
        function addFileToListBox(app,fileListBox,file)
           % show sif list
%            输入参数 ：
%                 fileListBox ： 列表UI对象
%                 file : 文件路径，char类型

            CellFileName = fileListBox.get('Items');
            StrFileName = string(CellFileName);
            
            % 转成字符串列表
            size = length(StrFileName);
            StrFile = string(file);
            sizeFile = length(StrFile);
            % 完整路径保存
            %StrFilePath = string(fullfile(path,file));
            
            if(StrFileName(1)=="")
                StrFileName(1:sizeFile) = StrFile;
            else
                StrFileName(size+1:size+sizeFile) = StrFile;
            end
            % 读取sif文件列表，添加到列表中
            fileListBox.set('Items',StrFileName);
            
            % 如果只有一个文件，则直接显示图像
            if(size == 1)
                app.FileListBox_2ValueChanged();
            end 
        end
      
    end


    methods (Access = private)

        % Callback function: Button_3, ShowAllFigureMenu
        function ShowAllImageButtonPushed(app, event)
            %   显示全部图像
       
            % 获取输入框中的文件夹路径
            fileFolder = app.FilefolderEditField.get();
            Matlab_sif_read(fileFolder.Value)
        end

        % Callback function: Button_2, ShowFileinListBoxMenu
        function ShowSifListButtonPushed(app, event)
            % show sif list
            % 显示文件列表
           
            % 获取输入框中的内容
            fileFolder = app.FilefolderEditField.get();
            
            % 读取sif文件列表，添加到列表中
            [fileNames,size] = MatlabUI_sif_file_list_read(fileFolder.Value);
            app.FileListBox.set('Items',fileNames);
            
            % 如果只有一个文件，则直接显示图像
            if(size == 1)
                app.FileListBoxValueChanged()
            end
        end

        % Value changed function: FileListBox
        function FileListBoxValueChanged(app, event)
            % show image
            % 显示所选文件的图像
            
             % 获取选中的列表的值
            value = app.FileListBox.Value;
            
            % 获取输入框中的内容
            fileFolder = app.FilefolderEditField.get();
            filePath = fullfile(fileFolder.Value,value);
            % 画图显示
            app.getOrignalDataPlot(filePath,app.UIAxes,1);

        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            %删除输入框内容
            app.FilefolderEditField.set('Value','');
        end

        % Callback function: Button, OpenFolderMenu
        function BrowseButtonPushed(app, event)
            % Browse
            % 选择打开的文件夹
            root = 'H:\Group_Work\Wyatt_Experiment\Raman_Spectroscopy\20181207';
            uiFileFolder = uigetdir(root);
            if(uiFileFolder)
                app.FilefolderEditField.set('Value',uiFileFolder);
            end
        end

        % Callback function: Button_4, SaveMenu
        function SaveAxesImageButtonPushed(app, event)
            % Save
            % 画图保存成图片格式

             % 获取选中的列表的值
            value = app.FileListBox.Value;
            
            % 获取输入框中的内容
            fileFolder = app.FilefolderEditField.get();
            filePath = fullfile(fileFolder.Value,value);
            
            % 输入参数为零，代表保存图片
            app.getOrignalDataPlot(filePath,0,1);
        end

        % Callback function: Button_5, SetMenu
        function SetButtonPushed(app, event)
            % Set 设置
            app.SetPanel.Visible = 'on';
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % Ok
            app.SetPanel.Visible = 'off';
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % 应用改变
            app.Xmin = app.XminSpinner.Value;
            app.Xmax = app.XmaxSpinner.Value;
            app.Ymin = app.YminSpinner.Value;
            app.Ymax = app.YmaxSpinner.Value;
            disp([app.Xmin app.Xmax app.Ymin app.Ymax]);
            if(app.Xmin < app.Xmax)
                app.UIAxes.XLim = [app.Xmin app.Xmax];
            end
            if(app.Ymin < app.Ymax)
                app.UIAxes.YLim = [app.Ymin app.Ymax];
            end
        end

        % Callback function: Button_6, ExportMenu
        function ExportButtonPushed(app, event)
            % 获取原始数据
            
            % 获取输入框中的内容
            fileFolder = app.FilefolderEditField.get();
            % 读取sif文件列表，添加到列表中
            [fileNames,size] = MatlabUI_sif_file_list_read(fileFolder.Value);
            
            for i = 1:size
                % 分割文件名
                S = regexp(fileNames(i), '\.', 'split')
                % 组合成一个新的文件名
              
                name = strcat(char(S{1}(1)),'.asc')
                               
                % 完整的输出文件夹的路径
                OutfileName = fullfile(fileFolder.Value,name);
                
                % 获取原始数据
                filePath = fullfile(fileFolder.Value,char(fileNames(i)));
                [pattern,calibvals,data,~,~,~,~] = MatlabUI_sif_show(filePath);
                
                if(pattern == '0')
                    WriteData(:,1) = calibvals;
                    WriteData(:,2) = data;
                    dlmwrite(OutfileName,WriteData,'delimiter','\t')
                elseif(pattern == '4')
                    dlmwrite(OutfileName,data,'delimiter','\t')
                end
            end
        end

        % Menu selected function: OpenFileMenu
        function OpenFileMenuSelected(app, event)
            [file,path] = uigetfile('*.sif');
            if(length(file)>1)
                filePath = fullfile(path,file);            
                %   画图
                app.getOrignalDataPlot(filePath,app.UIAxes,1);
                
                % 设置列表的值
                app.FileListBox.set('Items',string(file));
                app.FilefolderEditField.set('Value',string(path));
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.delete();
        end

        % Menu selected function: CalculationMenu
        function CalculationMenuSelected(app, event)
            
        end

        % Button pushed function: Button_7
        function BackgroundOpenButtonPushed(app, event)
            % 获取文件名
            [file,path] = uigetfile('');
            % app.addFileToListBox(app.FileListBox_2,file);
            
            filePath = fullfile(path,file);
            app.addFileToListBox(app.FileListBox_2,filePath);
        end

        % Value changed function: FileListBox_2
        function FileListBox_2ValueChanged(app, event)
            % show image 显示所选文件的图像
            
            % 获取选中的列表的值
            filePath = app.FileListBox_2.Value;
     
            % 画图显示
            app.getOrignalDataPlot(filePath,app.UIAxes_2,1);
        end

        % Menu selected function: SubtractBackgroundMenu
        function SubtractBackgroundMenuSelected(app, event)
            % 1. 获取信号数据
            
            % 获取选中的列表的值
            value = app.FileListBox.Value;
            % 获取输入框中的内容
            fileFolder = app.FilefolderEditField.get();
            filePath = fullfile(fileFolder.Value,value);
            [pattern1,calibvals1,data1,xtype1,xunit1,ytype1,yunit1] = app.getOrignalDataPlot(filePath,app.UIAxes,0);
            
            % 2. 获取背景信号
            % 获取选中的列表的值
            filePath = app.FileListBox_2.Value;
            [pattern2,calibvals2,data2,xtype2,xunit2,ytype2,yunit2] = app.getOrignalDataPlot(filePath,app.UIAxes_2,0);
            
            % 3.设置输出文件
            % 分割文件名
            S = regexp(value, '\.', 'split');
            % 组合成一个新的文件名
            name = strcat(char(S{1}),{'_subtract_background'},'.asc');              
            % 完整的输出文件夹的路径
            CellOutfileName = fullfile(fileFolder.Value,name);
            OutfileName = char(CellOutfileName);
            
            % 4.判断数据是否为同一个类型，大小是否相同
           if(pattern1 == pattern2)
                % disp("ok0")
                if(pattern1 == '0')
                    % disp("ok1")
                    if(calibvals1 == calibvals2)
                        % disp("ok2")
                        data = data1 - data2;
                        statu = app.singlePlot(0,calibvals1,data,xtype1,xunit1,ytype1,yunit1);
                        if(statu == 1)
                            % 保存数据
                            WriteData(:,1) = calibvals1;
                            WriteData(:,2) = data;
                            dlmwrite(OutfileName,WriteData,'delimiter','\t');
                        end
                    else
                        disp("坐标维度不同");
                        ShowMessage('错误','数据长度不同，不能操作！');
                    end
                elseif(pattern1 == '4')
                    data = data1 - data2;
                    statu = app.imageShow(0,data,xtype1,ytype1);
                    if(statu == 1)
                        dlmwrite(OutfileName,data,'delimiter','\t');
                    end
                end
            else
                % 错误
                disp("错误");
                ShowMessage('错误','数据类型不同，不能操作！');
            end
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            str = "";
            app.FileListBox_2.set('Items',str);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SifFileRead = uifigure;
            app.SifFileRead.Name = 'Sif_File_Read';
            app.FileMenu = uimenu(app.SifFileRead);
            app.FileMenu.Text = 'File';
            app.OpenFileMenu = uimenu(app.FileMenu);
            app.OpenFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuSelected, true);
            app.OpenFileMenu.Accelerator = 'O';
            app.OpenFileMenu.Text = 'Open File';
            app.OpenFolderMenu = uimenu(app.FileMenu);
            app.OpenFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.OpenFolderMenu.Accelerator = 'D';
            app.OpenFolderMenu.Text = 'Open Folder';
            app.ShowFileinListBoxMenu = uimenu(app.FileMenu);
            app.ShowFileinListBoxMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowSifListButtonPushed, true);
            app.ShowFileinListBoxMenu.Accelerator = 'A';
            app.ShowFileinListBoxMenu.Text = 'Show File in ListBox';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAxesImageButtonPushed, true);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportMenu.Separator = 'on';
            app.ExportMenu.Accelerator = 'E';
            app.ExportMenu.Text = 'Export';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.FigureMenu = uimenu(app.SifFileRead);
            app.FigureMenu.Text = 'Figure';
            app.SetMenu = uimenu(app.FigureMenu);
            app.SetMenu.MenuSelectedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetMenu.Text = 'Set';
            app.CalculationMenu = uimenu(app.FigureMenu);
            app.CalculationMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculationMenuSelected, true);
            app.CalculationMenu.Text = 'Calculation';
            app.SubtractBackgroundMenu = uimenu(app.CalculationMenu);
            app.SubtractBackgroundMenu.MenuSelectedFcn = createCallbackFcn(app, @SubtractBackgroundMenuSelected, true);
            app.SubtractBackgroundMenu.Accelerator = 'B';
            app.SubtractBackgroundMenu.Text = 'Subtract Background';
            app.ShowAllFigureMenu = uimenu(app.FigureMenu);
            app.ShowAllFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowAllImageButtonPushed, true);
            app.ShowAllFigureMenu.Text = 'Show All Figure';
            app.Menu = uimenu(app.SifFileRead);
            app.Menu.Text = 'Menu';
            app.TabGroup = uitabgroup(app.SifFileRead);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.XButton = uibutton(app.MainTab, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.FilefolderEditFieldLabel = uilabel(app.MainTab);
            app.FilefolderEditFieldLabel.Text = 'Filefolder';
            app.FilefolderEditField = uieditfield(app.MainTab, 'text');
            app.Button_2 = uibutton(app.MainTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ShowSifListButtonPushed, true);
            app.Button_2.Icon = 'icon_state_play.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.MainTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ShowAllImageButtonPushed, true);
            app.Button_3.Icon = 'FrequencyDomainAxes_24.png';
            app.Button_3.Text = '';
            app.Button = uibutton(app.MainTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.Button.Icon = 'icon_folder_open.png';
            app.Button.Text = '';
            app.Panel = uipanel(app.MainTab);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @SaveAxesImageButtonPushed, true);
            app.Button_4.Icon = 'Save.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.Button_5.Icon = 'gear_24.png';
            app.Button_5.Text = '';
            app.FileListBoxLabel = uilabel(app.MainTab);
            app.FileListBoxLabel.Text = 'File';
            app.FileListBox = uilistbox(app.MainTab);
            app.FileListBox.Items = {''};
            app.FileListBox.ValueChangedFcn = createCallbackFcn(app, @FileListBoxValueChanged, true);
            app.FileListBox.Value = '';
            app.Button_6 = uibutton(app.MainTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.Button_6.Icon = 'Export_16.png';
            app.Button_6.Text = '';
            app.SetPanel = uipanel(app.MainTab);
            app.SetPanel.Title = 'Set';
            app.SetPanel.Visible = 'off';
            app.TabGroup2 = uitabgroup(app.SetPanel);
            app.AxisTab = uitab(app.TabGroup2);
            app.AxisTab.Title = 'Axis';
            app.XminSpinnerLabel = uilabel(app.AxisTab);
            app.XminSpinnerLabel.Text = 'Xmin';
            app.XminSpinner = uispinner(app.AxisTab);
            app.XmaxSpinnerLabel = uilabel(app.AxisTab);
            app.XmaxSpinnerLabel.Text = 'Xmax';
            app.XmaxSpinner = uispinner(app.AxisTab);
            app.YminSpinnerLabel = uilabel(app.AxisTab);
            app.YminSpinnerLabel.Text = 'Ymin';
            app.YminSpinner = uispinner(app.AxisTab);
            app.YmaxSpinnerLabel = uilabel(app.AxisTab);
            app.YmaxSpinnerLabel.Text = 'Ymax';
            app.YmaxSpinner = uispinner(app.AxisTab);
            app.LineTab = uitab(app.TabGroup2);
            app.LineTab.Title = 'Line';
            app.ColorDropDownLabel = uilabel(app.LineTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.LineTab);
            app.ColorDropDown.Items = {'red', 'yellow', 'green', 'cyan', 'blue', 'magenta', 'white', 'black'};
            app.ColorDropDown.Value = 'blue';
            app.OkButton = uibutton(app.SetPanel, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.ApplyButton = uibutton(app.SetPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.BackgroundTab = uitab(app.TabGroup);
            app.BackgroundTab.Title = 'Background';
            app.Button_7 = uibutton(app.BackgroundTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @BackgroundOpenButtonPushed, true);
            app.Button_7.Icon = 'Open_16.png';
            app.Button_7.Text = '';
            app.FileListBox_2Label = uilabel(app.BackgroundTab);
            app.FileListBox_2Label.Text = 'File';
            app.FileListBox_2 = uilistbox(app.BackgroundTab);
            app.FileListBox_2.Items = {''};
            app.FileListBox_2.ValueChangedFcn = createCallbackFcn(app, @FileListBox_2ValueChanged, true);
            app.FileListBox_2.Value = '';
            app.UIAxes_2 = uiaxes(app.BackgroundTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.clearButton = uibutton(app.BackgroundTab, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Matlab_sif_readUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SifFileRead)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SifFileRead)
        end
    end
end