classdef Matlab_sif_readUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SifFileRead               matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        Menu_2                    matlab.ui.container.Menu
        Menu2                     matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        MainTab                   matlab.ui.container.Tab
        xButton                   matlab.ui.control.Button
        FilefolderEditFieldLabel  matlab.ui.control.Label
        FilefolderEditField       matlab.ui.control.EditField
        ShowFileListButton        matlab.ui.control.Button
        openButton                matlab.ui.control.Button
        BrosweButton              matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        SaveButton                matlab.ui.control.Button
        FileListBoxLabel          matlab.ui.control.Label
        FileListBox               matlab.ui.control.ListBox
        Tab2                      matlab.ui.container.Tab
    end


    methods (Access = private)
        
        % 画信号的图像
        function singlePlot(app,calibvals,data,xtype,xunit,ytype,yunit)
            y_max = max(data);
            size = length(calibvals);
            app.UIAxes.XLim = [calibvals(1) calibvals(size)];
            app.UIAxes.YLim = [0 y_max*1.05];
            plot(app.UIAxes,calibvals,data);
            title(app.UIAxes,'spectrum','fontsize',14);
            xlabel(app.UIAxes,{xtype;xunit});
            ylabel(app.UIAxes,{ytype;yunit});
            app.UIAxes.YAxis.Direction = 'normal';
            
        end
    
        % 显示image模式的图像
        function imageShow(app,data,xtype,ytype)
            data = data';
            app.UIAxes.XLim = [0 1024];
            app.UIAxes.YLim = [0 256];
            imagesc(app.UIAxes,data);
            title(app.UIAxes,'Image');
            xlabel(app.UIAxes,{xtype});
            ylabel(app.UIAxes,{ytype});
            colorbar(app.UIAxes);
        end
    
        % 获取原始的数据
        function [pattern,calibvals,data,xtype,xunit,ytype,yunit] = getOrignalData(app)
             % 获取选中的列表的值
            value = app.FileListBox.Value;
            
            % 获取输入框中的内容
            fileFolder = app.FilefolderEditField.get();
            filePath = fullfile(fileFolder.Value,value);
            
            % 获取原始数据
            [pattern,calibvals,data,xtype,xunit,ytype,yunit] = MatlabUI_sif_show(filePath);
        end
        
    end


    methods (Access = private)

        % Button pushed function: openButton
        function openButtonPushed(app, event)
            % 打开图像，TODO 保存成txt文件
            
            
            % 获取输入框中的文件夹路径
            fileFolder = app.FilefolderEditField.get();
            Matlab_sif_read(fileFolder.Value)
        end

        % Button pushed function: ShowFileListButton
        function ShowFileListButtonPushed(app, event)
            % 显示文件列表
            
            
            % 获取输入框中的内容
            fileFolder = app.FilefolderEditField.get();
            
            % 读取sif文件列表，添加到列表中
            [fileNames,size] = MatlabUI_sif_file_list_read(fileFolder.Value);
            app.FileListBox.set('Items',fileNames);
            
            % 如果只有一个文件，则直接显示图像
            if(size == 1)
                app.FileListBoxValueChanged()
            end
        end

        % Value changed function: FileListBox
        function FileListBoxValueChanged(app, event)
            % 显示所选文件的图像

            % 获取原始数据
            [pattern,calibvals,data,xtype,xunit,ytype,yunit] = app.getOrignalData();
            
            % 判断数据类型，然后显示出来； pattern=0为信号模式，pattern=4为图像模式
            if(pattern == '0')
                singlePlot(app,calibvals,data,xtype,xunit,ytype,yunit);            
            elseif(pattern == '4')
                app.imageShow(data,xtype,ytype);
            end
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            %删除输入框内容
            app.FilefolderEditField.set('Value','');
        end

        % Button pushed function: BrosweButton
        function BrosweButtonPushed(app, event)
            % 选择打开的文件夹
            uiFileFolder = uigetdir();
            if(uiFileFolder)
                app.FilefolderEditField.set('Value',uiFileFolder);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fig = figure;
            plot(1:10);
            [f,p]=uiputfile({'*.jpg'},'保存文件');
            str=strcat(p,f);
            print(fig,str,'-djpeg')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SifFileRead = uifigure;
            app.SifFileRead.Name = 'Sif_File_Read';
            app.Menu = uimenu(app.SifFileRead);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.SifFileRead);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.SifFileRead);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.xButton = uibutton(app.MainTab, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.FilefolderEditFieldLabel = uilabel(app.MainTab);
            app.FilefolderEditFieldLabel.Text = 'Filefolder';
            app.FilefolderEditField = uieditfield(app.MainTab, 'text');
            app.ShowFileListButton = uibutton(app.MainTab, 'push');
            app.ShowFileListButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFileListButtonPushed, true);
            app.ShowFileListButton.Text = 'ShowFileList';
            app.openButton = uibutton(app.MainTab, 'push');
            app.openButton.ButtonPushedFcn = createCallbackFcn(app, @openButtonPushed, true);
            app.openButton.Text = 'open';
            app.BrosweButton = uibutton(app.MainTab, 'push');
            app.BrosweButton.ButtonPushedFcn = createCallbackFcn(app, @BrosweButtonPushed, true);
            app.BrosweButton.Text = 'Broswe';
            app.Panel = uipanel(app.MainTab);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FileListBoxLabel = uilabel(app.MainTab);
            app.FileListBoxLabel.Text = 'File';
            app.FileListBox = uilistbox(app.MainTab);
            app.FileListBox.Items = {'Entry'};
            app.FileListBox.ValueChangedFcn = createCallbackFcn(app, @FileListBoxValueChanged, true);
            app.FileListBox.Value = 'Entry';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Matlab_sif_readUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SifFileRead)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SifFileRead)
        end
    end
end