classdef Matlab_sif_readUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SifFileRead               matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        Menu_2                    matlab.ui.container.Menu
        Menu2                     matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        MainTab                   matlab.ui.container.Tab
        XButton                   matlab.ui.control.Button
        FilefolderEditFieldLabel  matlab.ui.control.Label
        FilefolderEditField       matlab.ui.control.EditField
        Button_2                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        Button_4                  matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        FileListBoxLabel          matlab.ui.control.Label
        FileListBox               matlab.ui.control.ListBox
        Button_6                  matlab.ui.control.Button
        SetPanel                  matlab.ui.container.Panel
        TabGroup2                 matlab.ui.container.TabGroup
        AxisTab                   matlab.ui.container.Tab
        XminSpinnerLabel          matlab.ui.control.Label
        XminSpinner               matlab.ui.control.Spinner
        XmaxSpinnerLabel          matlab.ui.control.Label
        XmaxSpinner               matlab.ui.control.Spinner
        YminSpinnerLabel          matlab.ui.control.Label
        YminSpinner               matlab.ui.control.Spinner
        YmaxSpinnerLabel          matlab.ui.control.Label
        YmaxSpinner               matlab.ui.control.Spinner
        LineTab                   matlab.ui.container.Tab
        ColorDropDownLabel        matlab.ui.control.Label
        ColorDropDown             matlab.ui.control.DropDown
        OkButton                  matlab.ui.control.Button
        ApplyButton               matlab.ui.control.Button
        Tab2                      matlab.ui.container.Tab
    end


    properties (Access = private)
        % CopySetPanel  %     复制的最初使的设置值
        Xmin    %   X坐标轴最小值
        Xmax    %   X坐标轴最大值
        Ymin    %   Y坐标轴最小值
        Ymax    %   Y坐标轴最大值
    end

    methods (Access = private)
        
        % 画信号的图像
        function singlePlot(app,calibvals,data,xtype,xunit,ytype,yunit)
            y_max = max(data);
            size = length(calibvals);
            app.UIAxes.XLim = [calibvals(1) calibvals(size)];
            app.UIAxes.YLim = [0 y_max*1.05];
            plot(app.UIAxes,calibvals,data);
            title(app.UIAxes,'spectrum','fontsize',14);
            xlabel(app.UIAxes,{xtype;xunit});
            ylabel(app.UIAxes,{ytype;yunit});
            app.UIAxes.YAxis.Direction = 'normal';
            
        end
    
        % 显示image模式的图像
        function imageShow(app,data,xtype,ytype)
            data = data';
            app.UIAxes.XLim = [0 1024];
            app.UIAxes.YLim = [0 256];
            imagesc(app.UIAxes,data);
            title(app.UIAxes,'Image');
            xlabel(app.UIAxes,{xtype});
            ylabel(app.UIAxes,{ytype});
            colorbar(app.UIAxes);
        end
    
        % 获取原始的数据
        function [pattern,calibvals,data,xtype,xunit,ytype,yunit] = getOrignalData(app)
             % 获取选中的列表的值
            value = app.FileListBox.Value;
            
            % 获取输入框中的内容
            fileFolder = app.FilefolderEditField.get();
            filePath = fullfile(fileFolder.Value,value);
            
            % 获取原始数据
            [pattern,calibvals,data,xtype,xunit,ytype,yunit] = MatlabUI_sif_show(filePath);
        end
        
        % 保存sif数据成jpg图片
        function saveSifToJpg(app,fig)
            [f,p]=uiputfile({'*.jpg';'*.png'},'保存文件');
            if(length(f) > 1)
                str=strcat(p,f);
                if(contains(f,'.jpg'))
                    print(fig,str,'-djpeg')
                elseif(contains(f,'.png'))
                    print(fig,str,'-dpng')
                end
            end
        end
    
    end


    methods (Access = private)

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %   export
            % 打开图像，TODO 保存成txt文件
            
            
            % 获取输入框中的文件夹路径
            fileFolder = app.FilefolderEditField.get();
            Matlab_sif_read(fileFolder.Value)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % show sif list
            % 显示文件列表
           
            % 获取输入框中的内容
            fileFolder = app.FilefolderEditField.get();
            
            % 读取sif文件列表，添加到列表中
            [fileNames,size] = MatlabUI_sif_file_list_read(fileFolder.Value);
            app.FileListBox.set('Items',fileNames);
            
            % 如果只有一个文件，则直接显示图像
            if(size == 1)
                app.FileListBoxValueChanged()
            end
        end

        % Value changed function: FileListBox
        function FileListBoxValueChanged(app, event)
            % show image
            % 显示所选文件的图像

            % 获取原始数据
            [pattern,calibvals,data,xtype,xunit,ytype,yunit] = app.getOrignalData();
            
            % 判断数据类型，然后显示出来； pattern=0为信号模式，pattern=4为图像模式
            if(pattern == '0')
                singlePlot(app,calibvals,data,xtype,xunit,ytype,yunit);            
            elseif(pattern == '4')
                app.imageShow(data,xtype,ytype);
            end
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            %删除输入框内容
            app.FilefolderEditField.set('Value','');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Browse
            % 选择打开的文件夹
            
            uiFileFolder = uigetdir();
            if(uiFileFolder)
                app.FilefolderEditField.set('Value',uiFileFolder);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % Save
            % 画图保存成jpg图片格式
            
            fig = figure;
            % 获取原始数据
            [pattern,calibvals,data,xtype,xunit,ytype,yunit] = app.getOrignalData();
            
            % 判断数据类型，然后显示出来； pattern=0为信号模式，pattern=4为图像模式
            if(pattern == '0')
                plot(calibvals,data);
                title('spectrum');
                xlabel({xtype;xunit});
                ylabel({ytype;yunit});
                grid on;
                disp([app.Xmin app.Xmax app.Ymin app.Ymax]);
                if(app.Xmin < app.Xmax)
                    xlim([app.Xmin app.Xmax]);
                end
                if(app.Ymin < app.Ymax)
                    ylim([app.Ymin app.Ymax]);
                end
                
                
            elseif(pattern == '4')
                app.imageShow(data,xtype,ytype);
            end
            
            % 保存文件
           app.saveSifToJpg(fig);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            % Set 设置
            app.SetPanel.Visible = 'on';
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % Ok
            app.SetPanel.Visible = 'off';
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % 应用改变
            app.Xmin = app.XminSpinner.Value;
            app.Xmax = app.XmaxSpinner.Value;
            app.Ymin = app.YminSpinner.Value;
            app.Ymax = app.YmaxSpinner.Value;
            disp([app.Xmin app.Xmax app.Ymin app.Ymax]);
            if(app.Xmin < app.Xmax)
                app.UIAxes.XLim = [app.Xmin app.Xmax];
            end
            if(app.Ymin < app.Ymax)
                app.UIAxes.YLim = [app.Ymin app.Ymax];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SifFileRead = uifigure;
            app.SifFileRead.Name = 'Sif_File_Read';
            app.Menu = uimenu(app.SifFileRead);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.SifFileRead);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.SifFileRead);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.XButton = uibutton(app.MainTab, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.FilefolderEditFieldLabel = uilabel(app.MainTab);
            app.FilefolderEditFieldLabel.Text = 'Filefolder';
            app.FilefolderEditField = uieditfield(app.MainTab, 'text');
            app.Button_2 = uibutton(app.MainTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'icon_state_play.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.MainTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'FrequencyDomainAxes_24.png';
            app.Button_3.Text = '';
            app.Button = uibutton(app.MainTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icon_folder_open.png';
            app.Button.Text = '';
            app.Panel = uipanel(app.MainTab);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'Save.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'gear_24.png';
            app.Button_5.Text = '';
            app.FileListBoxLabel = uilabel(app.MainTab);
            app.FileListBoxLabel.Text = 'File';
            app.FileListBox = uilistbox(app.MainTab);
            app.FileListBox.Items = {''};
            app.FileListBox.ValueChangedFcn = createCallbackFcn(app, @FileListBoxValueChanged, true);
            app.FileListBox.Value = '';
            app.Button_6 = uibutton(app.MainTab, 'push');
            app.Button_6.Icon = 'Export_16.png';
            app.Button_6.Text = '';
            app.SetPanel = uipanel(app.MainTab);
            app.SetPanel.Title = 'Set';
            app.SetPanel.Visible = 'off';
            app.TabGroup2 = uitabgroup(app.SetPanel);
            app.AxisTab = uitab(app.TabGroup2);
            app.AxisTab.Title = 'Axis';
            app.XminSpinnerLabel = uilabel(app.AxisTab);
            app.XminSpinnerLabel.Text = 'Xmin';
            app.XminSpinner = uispinner(app.AxisTab);
            app.XmaxSpinnerLabel = uilabel(app.AxisTab);
            app.XmaxSpinnerLabel.Text = 'Xmax';
            app.XmaxSpinner = uispinner(app.AxisTab);
            app.YminSpinnerLabel = uilabel(app.AxisTab);
            app.YminSpinnerLabel.Text = 'Ymin';
            app.YminSpinner = uispinner(app.AxisTab);
            app.YmaxSpinnerLabel = uilabel(app.AxisTab);
            app.YmaxSpinnerLabel.Text = 'Ymax';
            app.YmaxSpinner = uispinner(app.AxisTab);
            app.LineTab = uitab(app.TabGroup2);
            app.LineTab.Title = 'Line';
            app.ColorDropDownLabel = uilabel(app.LineTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.LineTab);
            app.ColorDropDown.Items = {'red', 'yellow', 'green', 'cyan', 'blue', 'magenta', 'white', 'black'};
            app.ColorDropDown.Value = 'blue';
            app.OkButton = uibutton(app.SetPanel, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.ApplyButton = uibutton(app.SetPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Matlab_sif_readUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SifFileRead)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SifFileRead)
        end
    end
end